classdef THPPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        NghimTab                        matlab.ui.container.Tab
        NghiemSaiso                     matlab.ui.control.NumericEditField
        NhpkhongsaisEditFieldLabel      matlab.ui.control.Label
        Nghiemb                         matlab.ui.control.NumericEditField
        NhpkhongphnlybEditFieldLabel    matlab.ui.control.Label
        Nghiema                         matlab.ui.control.NumericEditField
        NhpkhongphnlyaEditFieldLabel    matlab.ui.control.Label
        NghiemPhuongtrinh               matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel       matlab.ui.control.Label
        NghiemSolanlap                  matlab.ui.control.EditField
        SoLanLapEditFieldLabel          matlab.ui.control.Label
        NghiemKetqua                    matlab.ui.control.EditField
        KetquaNghiemEditFieldLabel      matlab.ui.control.Label
        NghiemButtonRun                 matlab.ui.control.Button
        NghiemSelectPhuongphap          matlab.ui.container.ButtonGroup
        NghiemNewton                    matlab.ui.control.ToggleButton
        NghiemLap                       matlab.ui.control.ToggleButton
        NghiemChiadoi                   matlab.ui.control.ToggleButton
        NghiemUIAxes                    matlab.ui.control.UIAxes
        tabNoiSuy                       matlab.ui.container.Tab
        NoiSuyPhuongtrinhnoisuyEditField  matlab.ui.control.EditField
        phuongtrinhnoisuyEditFieldLabel  matlab.ui.control.Label
        NoiSuyDudoanyEditField          matlab.ui.control.NumericEditField
        dudoanyEditFieldLabel           matlab.ui.control.Label
        NoiSuyDudoanxEditField          matlab.ui.control.NumericEditField
        dudoanxEditFieldLabel           matlab.ui.control.Label
        NoiSuyPhuongphapnoisuyDropDown  matlab.ui.control.DropDown
        phuongphapnoisuyDropDownLabel   matlab.ui.control.Label
        NoiSuyYEditField                matlab.ui.control.EditField
        yEditFieldLabel                 matlab.ui.control.Label
        NoiSuyXEditField                matlab.ui.control.EditField
        xEditFieldLabel                 matlab.ui.control.Label
        NoiSuyButtonRun                 matlab.ui.control.Button
        NoiSuyUIAxes                    matlab.ui.control.UIAxes
        tabHoiQuy                       matlab.ui.container.Tab
        HoiQuyButtonRun                 matlab.ui.control.Button
        HoiQuyTextAreaKqdd              matlab.ui.control.TextArea
        HoiQuyTextAreaPthq              matlab.ui.control.TextArea
        HoiQuyEditFieldGtcdd            matlab.ui.control.EditField
        HoiQuyDropDown                  matlab.ui.control.DropDown
        HoiQuyEditFieldY                matlab.ui.control.EditField
        HoiQuyEditFieldX                matlab.ui.control.EditField
        HoiQuyUIAxes                    matlab.ui.control.UIAxes
        tabDaoHam                       matlab.ui.container.Tab
        DaoHamYiEditField               matlab.ui.control.EditField
        yiEditFieldLabel                matlab.ui.control.Label
        DaoHamXiEditField               matlab.ui.control.EditField
        xiEditFieldLabel                matlab.ui.control.Label
        DaoHamCtdrop                    matlab.ui.control.DropDown
        cchtnhDropDownLabel             matlab.ui.control.Label
        DaoHamHsccdrop                  matlab.ui.control.DropDown
        SaisctctLabel                   matlab.ui.control.Label
        DaoHamPpdrop                    matlab.ui.control.DropDown
        PPDropDownLabel                 matlab.ui.control.Label
        DaoHamHEditField                matlab.ui.control.NumericEditField
        NhphEditFieldLabel              matlab.ui.control.Label
        DaoHamXEditField                matlab.ui.control.NumericEditField
        NhpxEditFieldLabel_3            matlab.ui.control.Label
        DaoHamFxEditField               matlab.ui.control.EditField
        NhphmsEditFieldLabel            matlab.ui.control.Label
        DaoHamKqLabel                   matlab.ui.control.Label
        DaoHamButtonRun                 matlab.ui.control.Button
        TchphnTab                       matlab.ui.container.Tab
        TichPhanNhpyEditField           matlab.ui.control.EditField
        NhpyEditFieldLabel              matlab.ui.control.Label
        TichPhanNhpxEditField           matlab.ui.control.EditField
        NhpxEditFieldLabel_2            matlab.ui.control.Label
        TichPhanKtQuEditField           matlab.ui.control.EditField
        KtQuEditFieldLabel              matlab.ui.control.Label
        TichPhanNhpHmEditField          matlab.ui.control.EditField
        NhpHmEditFieldLabel             matlab.ui.control.Label
        TichPhanNhpaEditField           matlab.ui.control.NumericEditField
        NhpaEditFieldLabel              matlab.ui.control.Label
        TichPhanNhpNEditField           matlab.ui.control.NumericEditField
        NhpNEditFieldLabel              matlab.ui.control.Label
        TichPhanNhpbEditField           matlab.ui.control.NumericEditField
        NhpbLabel                       matlab.ui.control.Label
        hocLabel                        matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TichPhanButtonRun               matlab.ui.control.Button
        TichPhanPhngPhpButtonGroup      matlab.ui.container.ButtonGroup
        TichPhanSimpson38Button         matlab.ui.control.ToggleButton
        TichPhanSimpson13Button         matlab.ui.control.ToggleButton
        TichPhanHnhThangButton          matlab.ui.control.ToggleButton
        GiithiunhmTab                   matlab.ui.container.Tab
        TextArea_2                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NghiemButtonRun
        function NghiemButtonRunButtonPushed(app, event)
            if ~isempty(app.NghiemPhuongtrinh.Value)
                cla(app.NghiemUIAxes, 'reset');
                a = app.Nghiema.Value;
                b = app.Nghiemb.Value;
                saiso = app.NghiemSaiso.Value;
                f = app.NghiemPhuongtrinh.Value;
                c = 0;
                n = 0;
                if(app.NghiemChiadoi.Value == 1)
                    [c,n] = NghiemHamChiaDoi(f,a,b,saiso);  
                    f = str2func(['@(x)',f]);
                    hold(app.NghiemUIAxes,'on');
                    plot(app.NghiemUIAxes,linspace(a,b,10),f(linspace(a,b,10)));
                    plot(app.NghiemUIAxes,linspace(a,b,10),zeros(1,10));
                elseif(app.NghiemLap.Value == 1)
                    [c,n] = NghiemHamLap(f,a,b,saiso);
                    f = str2func(['@(x)',f]);
                    hold(app.NghiemUIAxes,'on');
                    plot(app.NghiemUIAxes,linspace(a,b,10),f(linspace(a,b,10)));
                    plot(app.NghiemUIAxes,linspace(a,b,10),linspace(a,b,10));
                elseif(app.NghiemNewton.Value == 1)
                    [c,n] = NghiemHamTiepTuyen(f,a,b,saiso);
                    f = str2func(['@(x)',f]);
                    hold(app.NghiemUIAxes,'on');
                    plot(app.NghiemUIAxes,linspace(a,b,10),f(linspace(a,b,10)));
                    plot(app.NghiemUIAxes,linspace(a,b,10),zeros(1,10));
                end
                app.NghiemKetqua.Value = c;
                app.NghiemSolanlap.Value = n;
            end
        end

        % Button pushed function: NoiSuyButtonRun
        function NoiSuyButtonRunButtonPushed(app, event)
            if and(~isempty(app.NoiSuyXEditField.Value),~isempty(app.NoiSuyYEditField.Value))
                cla(app.NoiSuyUIAxes, 'reset');
                x = str2num(app.NoiSuyXEditField.Value);
                y = str2num(app.NoiSuyYEditField.Value);
                xy = app.NoiSuyDudoanxEditField.Value;
                
                switch app.NoiSuyPhuongphapnoisuyDropDown.Value
                    case 'Lagrange' 
                        result = NoiSuyHamLagrange(x, y, xy);
                    case 'Newton'
                       result = NoiSuyHamNewtonInterpolation(x, y, xy);
                end
                app.NoiSuyPhuongtrinhnoisuyEditField.Value = string(NoiSuyHamLagrangeEquation(x,y));
                app.NoiSuyDudoanyEditField.Value = round(result,6);
                plot(app.NoiSuyUIAxes, x, y, 'o-');
                hold(app.NoiSuyUIAxes,'on');
                grid(app.NoiSuyUIAxes,'on');
                plot(app.NoiSuyUIAxes, xy, result, 'bx-');
            end
            
        end

        % Button pushed function: HoiQuyButtonRun
        function HoiQuyButtonRunButtonPushed(app, event)
            cla(app.HoiQuyUIAxes, 'reset');
            if and(~isempty(app.HoiQuyEditFieldX.Value),~isempty(app.HoiQuyEditFieldY.Value))
                x = str2num(app.HoiQuyEditFieldX.Value);
                y = str2num(app.HoiQuyEditFieldY.Value);

                switch app.HoiQuyDropDown.Value
                    case 1  
                        [A,B] = HoiQuyHamTuyenTinh(x,y);
                    case 2
                        [A,B] = HoiQuyHamTuyenTinh(x,log(y));
                    case 3
                        [A,B] = HoiQuyHamTuyenTinh(log(x),y);
                end
        
                switch app.HoiQuyDropDown.Value
                    case 1  
                        a = A;
                        b = B;
                        pthq = @(x) a.*x+b;
                        strpthq = [num2str(a),'*x + ',num2str(b)];
                    case 2
                        a = exp(B);
                        b = A;
                        pthq = @(x) a.*exp(b.*x);
                        strpthq = [num2str(a),' * e^(',num2str(b),'*x)'];
                    case 3
                        a = A;
                        b = B;
                        pthq = @(x) a.*log(x)+b;
                        strpthq = [num2str(a),'*ln(x) + ',num2str(b)];
                end

                hold(app.HoiQuyUIAxes, 'on' );
                plot(app.HoiQuyUIAxes,x,y,'o');
                plot(app.HoiQuyUIAxes,x(1):0.1:x(length(x)),pthq(x(1):0.1:x(length(x))));
                app.HoiQuyTextAreaPthq.Value = strpthq;
                
                if ~isempty(app.HoiQuyEditFieldGtcdd.Value)
                    gtcdd = str2num(app.HoiQuyEditFieldGtcdd.Value);
                    plot(app.HoiQuyUIAxes,gtcdd,pthq(gtcdd),'ro');
                    app.HoiQuyTextAreaKqdd.Value = num2str(pthq(gtcdd));
                end
            end
        end

        % Button pushed function: TichPhanButtonRun
        function TichPhanButtonRunButtonPushed(app, event)
             if or(and(~isempty(app.TichPhanNhpxEditField.Value),~isempty(app.TichPhanNhpyEditField.Value)),~isempty(app.TichPhanNhpHmEditField.Value))
                y = 0;
                n = 0;
                h = 0;
                if (~isempty(app.TichPhanNhpHmEditField.Value))
                    if(app.TichPhanHnhThangButton.Value==1)
                        app.Label.Text = ' ';
                        n = app.TichPhanNhpNEditField.Value;
                    elseif(app.TichPhanSimpson13Button.Value==1)
                        app.Label.Text = 'x2';
                        n = app.TichPhanNhpNEditField.Value * 2;
                    elseif(app.TichPhanSimpson38Button.Value==1)
                        app.Label.Text = 'x3';
                        n = app.TichPhanNhpNEditField.Value * 3;
                    end
                    a = app.TichPhanNhpaEditField.Value;
                    b = app.TichPhanNhpbEditField.Value;
                    h = (b-a)/n;
                    f = str2func(['@(x)',app.TichPhanNhpHmEditField.Value]);
                    x = a:h:b;
                    y = f(x);
                else
                    x = str2num(app.TichPhanNhpxEditField.Value);
                    y = str2num(app.TichPhanNhpyEditField.Value);
                    a = x(1);
                    b = x(length(x));
                    n = length(x)-1;
                    h = (b-a)/n;
                end
                
                if(app.TichPhanHnhThangButton.Value==1)
                    I = TichPhanHamHinhThang(y,h);
                    I = num2str(I);
                elseif(app.TichPhanSimpson13Button.Value==1)
                    if mod(n,2) ~= 0
                        I = 'n phai chia het cho 2';
                    else
                        I = TichPhanHamSimpson13(y,h);
                        I = num2str(I);
                    end
                elseif(app.TichPhanSimpson38Button.Value==1)
                    if mod(n,3) ~= 0
                        I = 'n phai chia het cho 3';
                    else
                        I = TichPhanHamSimpson38(y,h,n);
                        I = num2str(I);
                    end

                end
                app.TichPhanKtQuEditField.Value = I;
             end
        end

        % Button pushed function: DaoHamButtonRun
        function DaoHamButtonRunPushed(app, event)
            if or(and(app.DaoHamCtdrop.Value == 'xy',and(~isempty(app.DaoHamXiEditField.Value),~isempty(app.DaoHamYiEditField.Value))), ...
                    and(app.DaoHamCtdrop.Value == 'fx',and(~isempty(app.DaoHamHEditField.Value),~isempty(app.DaoHamFxEditField.Value))))
                xi=str2num(app.DaoHamXiEditField.Value);
                yi=str2num(app.DaoHamYiEditField.Value);
                fx=app.DaoHamFxEditField.Value;
                x=app.DaoHamXEditField.Value;
                h=app.DaoHamHEditField.Value;
                ct=app.DaoHamCtdrop.Value;
                pp=app.DaoHamPpdrop.Value;
                hscc=app.DaoHamHsccdrop.Value;
                dx = DaoHamHamTaylor(xi,yi,fx,h,x,ct,pp,hscc);
                app.DaoHamKqLabel.Text = num2str(dx);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.NghiemUIAxes = uiaxes(app.NghimTab);
            title(app.NghiemUIAxes, 'Title')
            xlabel(app.NghiemUIAxes, 'X')
            ylabel(app.NghiemUIAxes, 'Y')
            zlabel(app.NghiemUIAxes, 'Z')
            app.NghiemSelectPhuongphap = uibuttongroup(app.NghimTab);
            app.NghiemSelectPhuongphap.Title = 'Chọn phương pháp tìm';
            app.NghiemChiadoi = uitogglebutton(app.NghiemSelectPhuongphap);
            app.NghiemChiadoi.Text = 'Chia đôi';
            app.NghiemChiadoi.Value = true;
            app.NghiemLap = uitogglebutton(app.NghiemSelectPhuongphap);
            app.NghiemLap.Text = 'Lặp';
            app.NghiemNewton = uitogglebutton(app.NghiemSelectPhuongphap);
            app.NghiemNewton.Text = 'Newton (tiếp tuyến)';
            app.NghiemButtonRun = uibutton(app.NghimTab, 'push');
            app.NghiemButtonRun.ButtonPushedFcn = createCallbackFcn(app, @NghiemButtonRunButtonPushed, true);
            app.NghiemButtonRun.Text = 'Tìm Nghiệm';
            app.KetquaNghiemEditFieldLabel = uilabel(app.NghimTab);
            app.KetquaNghiemEditFieldLabel.Text = 'Ket qua Nghiem';
            app.NghiemKetqua = uieditfield(app.NghimTab, 'text');
            app.NghiemKetqua.Editable = 'off';
            app.SoLanLapEditFieldLabel = uilabel(app.NghimTab);
            app.SoLanLapEditFieldLabel.Text = 'So Lan Lap';
            app.NghiemSolanlap = uieditfield(app.NghimTab, 'text');
            app.NghiemSolanlap.Editable = 'off';
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghimTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.NghiemPhuongtrinh = uieditfield(app.NghimTab, 'text');
            app.NhpkhongphnlyaEditFieldLabel = uilabel(app.NghimTab);
            app.NhpkhongphnlyaEditFieldLabel.Text = 'Nhập khoảng phân ly a';
            app.Nghiema = uieditfield(app.NghimTab, 'numeric');
            app.NhpkhongphnlybEditFieldLabel = uilabel(app.NghimTab);
            app.NhpkhongphnlybEditFieldLabel.Text = 'Nhập khoảng phân ly b';
            app.Nghiemb = uieditfield(app.NghimTab, 'numeric');
            app.NhpkhongsaisEditFieldLabel = uilabel(app.NghimTab);
            app.NhpkhongsaisEditFieldLabel.Text = 'Nhập khoảng sai số';
            app.NghiemSaiso = uieditfield(app.NghimTab, 'numeric');
            app.tabNoiSuy = uitab(app.TabGroup);
            app.tabNoiSuy.Title = 'Nội suy';
            app.NoiSuyUIAxes = uiaxes(app.tabNoiSuy);
            title(app.NoiSuyUIAxes, 'Title')
            xlabel(app.NoiSuyUIAxes, 'X')
            ylabel(app.NoiSuyUIAxes, 'Y')
            zlabel(app.NoiSuyUIAxes, 'Z')
            app.NoiSuyButtonRun = uibutton(app.tabNoiSuy, 'push');
            app.NoiSuyButtonRun.ButtonPushedFcn = createCallbackFcn(app, @NoiSuyButtonRunButtonPushed, true);
            app.xEditFieldLabel = uilabel(app.tabNoiSuy);
            app.xEditFieldLabel.Text = 'x';
            app.NoiSuyXEditField = uieditfield(app.tabNoiSuy, 'text');
            app.yEditFieldLabel = uilabel(app.tabNoiSuy);
            app.yEditFieldLabel.Text = 'y';
            app.NoiSuyYEditField = uieditfield(app.tabNoiSuy, 'text');
            app.phuongphapnoisuyDropDownLabel = uilabel(app.tabNoiSuy);
            app.phuongphapnoisuyDropDownLabel.Text = 'phuong phap noi suy';
            app.NoiSuyPhuongphapnoisuyDropDown = uidropdown(app.tabNoiSuy);
            app.NoiSuyPhuongphapnoisuyDropDown.Items = {'Lagrange', 'Newton'};
            app.NoiSuyPhuongphapnoisuyDropDown.Value = 'Lagrange';
            app.dudoanxEditFieldLabel = uilabel(app.tabNoiSuy);
            app.dudoanxEditFieldLabel.Text = 'du doan x';
            app.NoiSuyDudoanxEditField = uieditfield(app.tabNoiSuy, 'numeric');
            app.dudoanyEditFieldLabel = uilabel(app.tabNoiSuy);
            app.dudoanyEditFieldLabel.Text = 'du doan y';
            app.NoiSuyDudoanyEditField = uieditfield(app.tabNoiSuy, 'numeric');
            app.NoiSuyDudoanyEditField.Editable = 'off';
            app.phuongtrinhnoisuyEditFieldLabel = uilabel(app.tabNoiSuy);
            app.phuongtrinhnoisuyEditFieldLabel.Text = 'phuong trinh noi suy';
            app.NoiSuyPhuongtrinhnoisuyEditField = uieditfield(app.tabNoiSuy, 'text');
            app.NoiSuyPhuongtrinhnoisuyEditField.Editable = 'off';
            app.tabHoiQuy = uitab(app.TabGroup);
            app.tabHoiQuy.Title = 'Hồi quy';
            app.HoiQuyUIAxes = uiaxes(app.tabHoiQuy);
            title(app.HoiQuyUIAxes, 'Đồ thị hàm hồi quy và dữ liệu thực')
            xlabel(app.HoiQuyUIAxes, 'X')
            ylabel(app.HoiQuyUIAxes, 'Y')
            zlabel(app.HoiQuyUIAxes, 'Z')
            app.HoiQuyEditFieldX = uieditfield(app.tabHoiQuy, 'text');
            app.HoiQuyEditFieldX.Placeholder = 'Nhập dữ liệu x';
            app.HoiQuyEditFieldY = uieditfield(app.tabHoiQuy, 'text');
            app.HoiQuyEditFieldY.Placeholder = 'Nhập dữ liệu y';
            app.HoiQuyDropDown = uidropdown(app.tabHoiQuy);
            app.HoiQuyDropDown.Items = {'Hàm tuyến tính', 'Hàm mũ', 'Hàm logarit'};
            app.HoiQuyDropDown.ItemsData = [1 2 3];
            app.HoiQuyDropDown.Value = 1;
            app.HoiQuyEditFieldGtcdd = uieditfield(app.tabHoiQuy, 'text');
            app.HoiQuyEditFieldGtcdd.Placeholder = 'Nhập giá trị cần dự đoán';
            app.HoiQuyTextAreaPthq = uitextarea(app.tabHoiQuy);
            app.HoiQuyTextAreaPthq.Editable = 'off';
            app.HoiQuyTextAreaPthq.Placeholder = 'Kết quả phương trình hồi quy';
            app.HoiQuyTextAreaKqdd = uitextarea(app.tabHoiQuy);
            app.HoiQuyTextAreaKqdd.Editable = 'off';
            app.HoiQuyTextAreaKqdd.Placeholder = 'Kết quả dự đoán';
            app.HoiQuyButtonRun = uibutton(app.tabHoiQuy, 'push');
            app.HoiQuyButtonRun.ButtonPushedFcn = createCallbackFcn(app, @HoiQuyButtonRunButtonPushed, true);
            app.HoiQuyButtonRun.Text = 'Run';
            app.tabDaoHam = uitab(app.TabGroup);
            app.tabDaoHam.Title = 'Đạo hàm';
            app.DaoHamButtonRun = uibutton(app.tabDaoHam, 'push');
            app.DaoHamButtonRun.ButtonPushedFcn = createCallbackFcn(app, @DaoHamButtonRunPushed, true);
            app.DaoHamButtonRun.Text = 'Tính ĐH';
            app.DaoHamKqLabel = uilabel(app.tabDaoHam);
            app.DaoHamKqLabel.Text = 'Kết quả';
            app.NhphmsEditFieldLabel = uilabel(app.tabDaoHam);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.DaoHamFxEditField = uieditfield(app.tabDaoHam, 'text');
            app.DaoHamFxEditField.Value = 'asin(x)';
            app.NhpxEditFieldLabel_3 = uilabel(app.tabDaoHam);
            app.NhpxEditFieldLabel_3.Text = 'Nhập x';
            app.DaoHamXEditField = uieditfield(app.tabDaoHam, 'numeric');
            app.DaoHamXEditField.Value = 0.5;
            app.NhphEditFieldLabel = uilabel(app.tabDaoHam);
            app.NhphEditFieldLabel.Text = 'Nhập h';
            app.DaoHamHEditField = uieditfield(app.tabDaoHam, 'numeric');
            app.DaoHamHEditField.Value = 0.2;
            app.PPDropDownLabel = uilabel(app.tabDaoHam);
            app.PPDropDownLabel.Text = 'PP';
            app.DaoHamPpdrop = uidropdown(app.tabDaoHam);
            app.DaoHamPpdrop.Items = {'Tiến', 'Lùi', 'Trung tâm'};
            app.DaoHamPpdrop.Value = 'Tiến';
            app.SaisctctLabel = uilabel(app.tabDaoHam);
            app.SaisctctLabel.Text = 'Sai số cắt cụt';
            app.DaoHamHsccdrop = uidropdown(app.tabDaoHam);
            app.DaoHamHsccdrop.Items = {'O(h)', 'O(h^2)'};
            app.DaoHamHsccdrop.Value = 'O(h)';
            app.cchtnhDropDownLabel = uilabel(app.tabDaoHam);
            app.cchtnhDropDownLabel.Text = 'cách tính';
            app.DaoHamCtdrop = uidropdown(app.tabDaoHam);
            app.DaoHamCtdrop.Items = {'xy', 'fx'};
            app.DaoHamCtdrop.Value = 'xy';
            app.xiEditFieldLabel = uilabel(app.tabDaoHam);
            app.xiEditFieldLabel.Text = 'xi';
            app.DaoHamXiEditField = uieditfield(app.tabDaoHam, 'text');
            app.DaoHamXiEditField.Value = '0.1 0.3 0.5 0.7 0.9';
            app.yiEditFieldLabel = uilabel(app.tabDaoHam);
            app.yiEditFieldLabel.Text = 'yi';
            app.DaoHamYiEditField = uieditfield(app.tabDaoHam, 'text');
            app.DaoHamYiEditField.Value = '0.1002 0.3047 0.5236 0.7754 1.1198';
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.TichPhanPhngPhpButtonGroup = uibuttongroup(app.TchphnTab);
            app.TichPhanPhngPhpButtonGroup.Title = 'Phương Pháp';
            app.TichPhanHnhThangButton = uitogglebutton(app.TichPhanPhngPhpButtonGroup);
            app.TichPhanHnhThangButton.Text = 'Hình Thang';
            app.TichPhanHnhThangButton.Value = true;
            app.TichPhanSimpson13Button = uitogglebutton(app.TichPhanPhngPhpButtonGroup);
            app.TichPhanSimpson13Button.Text = 'Simpson1/3';
            app.TichPhanSimpson38Button = uitogglebutton(app.TichPhanPhngPhpButtonGroup);
            app.TichPhanSimpson38Button.Text = 'Simpson3/8';
            app.TichPhanButtonRun = uibutton(app.TchphnTab, 'push');
            app.TichPhanButtonRun.ButtonPushedFcn = createCallbackFcn(app, @TichPhanButtonRunButtonPushed, true);
            app.TichPhanButtonRun.Text = 'Tính';
            app.Label = uilabel(app.TchphnTab);
            app.Label.Text = '';
            app.hocLabel = uilabel(app.TchphnTab);
            app.hocLabel.Text = 'hoặc';
            app.NhpbLabel = uilabel(app.TchphnTab);
            app.NhpbLabel.Text = 'Nhập b';
            app.TichPhanNhpbEditField = uieditfield(app.TchphnTab, 'numeric');
            app.NhpNEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N';
            app.TichPhanNhpNEditField = uieditfield(app.TchphnTab, 'numeric');
            app.NhpaEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpaEditFieldLabel.Text = 'Nhập a';
            app.TichPhanNhpaEditField = uieditfield(app.TchphnTab, 'numeric');
            app.NhpHmEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpHmEditFieldLabel.Text = 'Nhập Hàm';
            app.TichPhanNhpHmEditField = uieditfield(app.TchphnTab, 'text');
            app.KtQuEditFieldLabel = uilabel(app.TchphnTab);
            app.KtQuEditFieldLabel.Text = 'Kết Quả';
            app.TichPhanKtQuEditField = uieditfield(app.TchphnTab, 'text');
            app.NhpxEditFieldLabel_2 = uilabel(app.TchphnTab);
            app.NhpxEditFieldLabel_2.Text = 'Nhập x';
            app.TichPhanNhpxEditField = uieditfield(app.TchphnTab, 'text');
            app.NhpyEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpyEditFieldLabel.Text = 'Nhập y';
            app.TichPhanNhpyEditField = uieditfield(app.TchphnTab, 'text');
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.TextArea = uitextarea(app.GiithiunhmTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Thành Viên'; 'Huỳnh Thanh Phong - 21207191'; 'Ninh Duy Phúc - 21207198'; 'Lê Nguyễn Hải Ninh - 21207189'; 'Nguyễn Đức Quang - 21207207'; 'Trần Phước Linh - 21207177'; ''; ''};
            app.TextArea_2 = uitextarea(app.GiithiunhmTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Nhiêm vụ'; 'Hồi quy, hoàn thành app'; 'Đạo hàm'; 'Nội suy, báo cáo'; 'Nghiệm, báo cáo'; 'Tich Phân'; ''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = THPPT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end